classdef iqm_dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        IQMMortalittsindikatorenLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        MortalittTab                   matlab.ui.container.Tab
        PSITab                         matlab.ui.container.Tab
        RehospTab                      matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        IndikatorDropDown              matlab.ui.control.DropDown
        IndikatorDropDownLabel         matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tab_ind=[];

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);

          mort_data= readtable([path,'\mort_data.xlsx']);
          ind=[];
          ind=mort_data(1,:);
          ind.indicator='Bitte Indikator auswählen';
          mort_data=[ind;mort_data];
           my_mort_inds=unique(mort_data.indicator,'stable');
            app.IndikatorDropDown.Items = my_mort_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_mort_inds)];
          psi_data=readtable([path, '\psi_data.xlsx']);
          ind=[];
          ind=psi_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          psi_data=[ind;psi_data];

          rehosp_data=readtable([path, '\rehosp_data.xlsx']);
          ind=[];
          ind=rehosp_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          rehosp_data=[ind;rehosp_data];


 app.tab_ind=1;

        end

        % Drop down opening function: IndikatorDropDown
        function IndikatorDropDownOpening(app, event)

        end

        % Value changed function: IndikatorDropDown
        function IndikatorDropDownValueChanged(app, event)
    
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Mortaliyt 
                        path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);
            if app.tab_ind==1
          
                 mort_data= readtable([path, '\mort_data.xlsx']);
                 
                 ind=[];
          ind=mort_data(1,:);
          ind.indicator='Bitte Indikator auswählen';
          mort_data=[ind;mort_data];
               
            val=app.IndikatorDropDown.Value;
              disp(num2str(val))
            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(mort_data.indicator,this_data));
            this_data=mort_data(this_data,:);

            data_smr=[];
            data_smr=this_data.smr;
            data_observed=[];
            data_observed=this_data.observed;
            data_expected=[];
            data_expected=this_data.expected;
            data_n_cases=[];
            data_n_cases=this_data.n_cases;
            data_n_all=[];
            data_n_all=this_data.n_all;

            plot(app.UIAxes, data_smr,'--','Color','black')
            hold on
         app.UIAxes.YGrid='on';

         yline(app.UIAxes,1)


            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([this_data.year{t},',N= ',num2str(this_data.n_all(t))]);
                  all_labels=[all_labels;string(this_label)];
            end


            xlabel(app.UIAxes,'Quartal');
            ylabel(app.UIAxes,'SMR');
            app.UIAxes.XTick=[1:numel(unique(this_data.year))];

            if anynan(data_smr)==0
                
            app.UIAxes.XTickLabel=all_labels;
            else app.UIAxes.XTickLabel={};
                drawnow
            end

            if val <2
                app.UIAxes.XTickLabel={};
            end
             % app.UIAxes.Title.String=this_data.indicator{1}

            % Expected/observed Plot
           % mort_data= readtable(path,\mort_data.xlsx");
            val=app.IndikatorDropDown.Value;

            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(mort_data.indicator,this_data));
            this_data=mort_data(this_data,:);

            data_smr=[];
            data_smr=this_data.smr;
            data_observed=[];
            data_observed=this_data.observed;
            data_expected=[];
            data_expected=this_data.expected;
            data_n_cases=[];
            data_n_cases=this_data.n_cases;
            data_n_all=[];
            data_n_all=this_data.n_all;

            plot(app.UIAxes_2, data_observed,'--')
            hold (app.UIAxes_2,'on')
            plot(app.UIAxes_2, data_expected,'--')
            app.UIAxes_2.YGrid='on';
            legend(app.UIAxes_2, 'Beobachtet', 'Erwartet','Location','east');
            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([this_data.year{t},',N= ',num2str(this_data.n_all(t))]);
                  all_labels=[all_labels;string(this_label)];
            end

            xlabel(app.UIAxes_2,'Quartal');
            ylabel(app.UIAxes_2,'Rate %');
            app.UIAxes_2.XTick=[1:numel(unique(this_data.year))];
            app.UIAxes_2.XTickLabel=all_labels;
           
                if val <2
                app.UIAxes_2.XTickLabel={};
            end
             hold (app.UIAxes_2,'off')


            if anynan(data_smr)==1
                app.Label.Text='Kein SMR vorhanden'
            else app.Label.Text=''
                drawnow
            end

           if anynan(data_expected)==1
                app.Label_2.Text='Keine erwartete Rate vorhanden'
           else app.Label_2.Text=''
               drawnow
           end

           if anynan(data_observed)==1
                app.Label_3.Text='Keine beobachtete Rate vorhanden'
           else app.Label_3.Text=''
               drawnow
            end  

             end
      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           % PSI

           if  app.tab_ind==2
                      

            psi_data= readtable([path, '\psi_data.xlsx']);
            ind=[];
          ind=psi_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          psi_data=[ind;psi_data];

            val=app.IndikatorDropDown.Value;
            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(psi_data.Indikator,this_data));
            this_data=psi_data(this_data,:);

            data_smr=[];
            data_smr=this_data.RisikobereinigteSCR;
            data_observed=[];
            data_observed=this_data.beobachtete_rate;
            data_expected=[];
            data_expected=this_data.erwartete_rate;
            data_n_cases=[];
            data_n_cases=this_data.BeobachteteKomplikationen;
            data_n_all=[];
            data_n_all=this_data.Ber_cksichtigteAustritte;

            plot(app.UIAxes, data_smr,'--','Color','black')
            drawnow;
            hold on
         app.UIAxes.YGrid='on';
          
         yline(app.UIAxes,1)


            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([num2str(this_data.year(t)),',N= ',num2str(this_data.Ber_cksichtigteAustritte(t))]);
                  all_labels=[all_labels;string(this_label)];
            end


            xlabel(app.UIAxes,'Jahr');
            ylabel(app.UIAxes,'SCR');
            app.UIAxes.XTick=[1:numel(unique(this_data.year))];
            
            if anynan(data_smr)==0
            app.UIAxes.XTickLabel=all_labels;
            else app.UIAxes.XTickLabel={};
                drawnow
            end

                if val <2
                app.UIAxes.XTickLabel={};
            end
             % app.UIAxes.Title.String=this_data.indicator{1}

            % Expected/observed Plot
           % psi_data= readtable(path,\psi_data.xlsx");
            val=app.IndikatorDropDown.Value;
           
            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(psi_data.Indikator,this_data));
            this_data=psi_data(this_data,:);

            data_smr=[];
            data_smr=this_data.RisikobereinigteSCR;
            data_observed=[];
            data_observed=this_data.beobachtete_rate;
            data_expected=[];
            data_expected=this_data.erwartete_rate;
            data_n_cases=[];
            data_n_cases=this_data.BeobachteteKomplikationen;
            data_n_all=[];
            data_n_all=this_data.Ber_cksichtigteAustritte;

            plot(app.UIAxes_2, data_observed,'--')
            hold (app.UIAxes_2,'on')
            plot(app.UIAxes_2, data_expected,'--')
            app.UIAxes_2.YGrid='on';
            legend(app.UIAxes_2, 'Beobachtet', 'Erwartet','Location','east');


            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([num2str(this_data.year(t)),',N= ',num2str(this_data.Ber_cksichtigteAustritte(t))]);
                  all_labels=[all_labels;string(this_label)];
            end

            xlabel(app.UIAxes_2,'Jahr');
            ylabel(app.UIAxes_2,'Rate %');
            app.UIAxes_2.XTick=[1:numel(unique(this_data.year))];
            app.UIAxes_2.XTickLabel=all_labels;
                if val <2
                app.UIAxes_2.XTickLabel={};
            end
             hold (app.UIAxes_2,'off')
              

            if anynan(data_smr)==1
                app.Label.Text='Kein SCR vorhanden'
            else app.Label.Text=''
                drawnow
            end

           if anynan(data_expected)==1
                app.Label_2.Text='Keine erwartete Rate vorhanden'
           else app.Label_2.Text=''
               drawnow
           end

           if anynan(data_observed)==1
                app.Label_3.Text='Keine beobachtete Rate vorhanden'
           else app.Label_3.Text=''
               drawnow
            end  
           end
     
      %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
      % Rehosp
           if app.tab_ind==3
          
                 rehosp_data=readtable([path, '\rehosp_data.xlsx']);
                 ind=[];
          ind=rehosp_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          rehosp_data=[ind;rehosp_data];
               
            val=app.IndikatorDropDown.Value;
              disp(num2str(val))
            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(rehosp_data.Indikator,this_data));
            this_data=rehosp_data(this_data,:);

            data_smr=[];
            data_smr=this_data.RisikobereinigteSRR;
            data_observed=[];
            data_observed=this_data.beobachtete_rate;
            data_expected=[];
            data_expected=this_data.erwartete_rate;
            data_n_cases=[];
            data_n_cases=this_data.BeobachteteUngepl_Rehosp_;
            data_n_all=[];
            data_n_all=this_data.Ber_cksichtigteAustritte;

            plot(app.UIAxes, data_smr,'--','Color','black')
            hold on
         app.UIAxes.YGrid='on';

         yline(app.UIAxes,1)


            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([num2str(this_data.year(t)),',N= ',num2str(this_data.Ber_cksichtigteAustritte(t))]);
                  all_labels=[all_labels;string(this_label)];
            end


            xlabel(app.UIAxes,'Jahr');
            ylabel(app.UIAxes,'SRR');
            app.UIAxes.XTick=[1:numel(unique(this_data.year))];

            if anynan(data_smr)==0
                
            app.UIAxes.XTickLabel=all_labels;
            else app.UIAxes.XTickLabel={};
                drawnow
            end

            if val <2
                app.UIAxes.XTickLabel={};
            end
             % app.UIAxes.Title.String=this_data.indicator{1}

            % Expected/observed Plot
            val=app.IndikatorDropDown.Value;

            this_data=[];
            this_data=app.IndikatorDropDown.Items(val);
            this_data=find(strcmp(rehosp_data.Indikator,this_data));
            this_data=rehosp_data(this_data,:);

            data_smr=[];
            data_smr=this_data.RisikobereinigteSRR;
            data_observed=[];
            data_observed=this_data.beobachtete_rate;
            data_expected=[];
            data_expected=this_data.erwartete_rate;
            data_n_cases=[];
            data_n_cases=this_data.BeobachteteUngepl_Rehosp_;
            data_n_all=[];
            data_n_all=this_data.Ber_cksichtigteAustritte;

            plot(app.UIAxes_2, data_observed,'--')
            hold (app.UIAxes_2,'on')
            plot(app.UIAxes_2, data_expected,'--')
            app.UIAxes_2.YGrid='on';
            legend(app.UIAxes_2, 'Beobachtet', 'Erwartet','Location','east');
            % create xticklabels with N
            all_labels=[];
            for t=1:size(this_data,1)
                  this_label=[];
                  this_label=([num2str(this_data.year(t)),',N= ',num2str(this_data.Ber_cksichtigteAustritte(t))]);
                  all_labels=[all_labels;string(this_label)];
            end

            xlabel(app.UIAxes_2,'Jahr');
            ylabel(app.UIAxes_2,'Rate %');
            app.UIAxes_2.XTick=[1:numel(unique(this_data.year))];
            app.UIAxes_2.XTickLabel=all_labels;
           
                if val <2
                app.UIAxes_2.XTickLabel={};
            end
             hold (app.UIAxes_2,'off')


            if anynan(data_smr)==1
                app.Label.Text='Kein SMR vorhanden'
            else app.Label.Text=''
                drawnow
            end

           if anynan(data_expected)==1
                app.Label_2.Text='Keine erwartete Rate vorhanden'
           else app.Label_2.Text=''
               drawnow
           end

           if anynan(data_observed)==1
                app.Label_3.Text='Keine beobachtete Rate vorhanden'
           else app.Label_3.Text=''
               drawnow
            end  

           end


        end

        % Clicked callback: IndikatorDropDown
        function IndikatorDropDownClicked(app, event)
        
            path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);
            if app.tab_ind==1
             mort_data=readtable([path, '\mort_data.xlsx']);
          ind=[];
          ind=mort_data(1,:);
          ind.indicator='Bitte Indikator auswählen';
          mort_data=[ind;mort_data];
           my_mort_inds=unique(mort_data.indicator,'stable');
            app.IndikatorDropDown.Items = my_mort_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_mort_inds)];
             val=app.IndikatorDropDown.Value;
         end
         if app.tab_ind==2
              psi_data=readtable([path, '\psi_data.xlsx']);
          ind=[];
          ind=psi_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          psi_data=[ind;psi_data];
          my_psi_inds=unique(psi_data.Indikator,'stable');
            app.IndikatorDropDown.Items = my_psi_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_psi_inds)];
             val=app.IndikatorDropDown.Value;
         end

         if app.tab_ind==3
              rehosp_data=readtable([path, '\rehosp_data.xlsx']);
          ind=[];
          ind=rehosp_data(1,:);
          ind.Indikator='Bitte Indikator auswählen';
          rehosp_data=[ind;rehosp_data];
          my_rehosp_inds=unique(rehosp_data.Indikator,'stable');
            app.IndikatorDropDown.Items = my_rehosp_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_rehosp_inds)];
             val=app.IndikatorDropDown.Value;
         end

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Callback function
        function ErwarteteundBeobachteteRateanzeigenButtonValueChanged(app, event)
 

        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)
           
        end

        % Button down function: PSITab
        function PSITabButtonDown(app, event)
                   
                        path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);
            psi_data=readtable([path, '\psi_data.xlsx']);
                     my_psi_inds=unique(psi_data.Indikator,'stable');
                    app.IndikatorDropDown.Items = my_psi_inds;
                    app.IndikatorDropDown.ItemsData=[1:numel(my_psi_inds)];
                    app.tab_ind=2; 
                    disp(num2str(app.tab_ind))
            app.IQMMortalittsindikatorenLabel.Text='Patient Saftey Indicators';
           drawnow;
                
              
        end

        % Button down function: MortalittTab
        function MortalittTabButtonDown(app, event)
           
                        path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);
            mort_data= readtable([path, '\mort_data.xlsx']);
            my_mort_inds=unique(mort_data.indicator);
            app.IndikatorDropDown.Items = my_mort_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_mort_inds)];
                app.tab_ind=1;  
                disp(num2str(app.tab_ind))
                 app.IQMMortalittsindikatorenLabel.Text='Mortalitätsindikatoren';
           drawnow;

      
        end

        % Callback function
        function RehospTabButtonDown(app, event)

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % this_tab = app.TabGroup.SelectedTab;
            % fprintf(this_tab.Title)
          

        end

        % Button down function: RehospTab
        function RehospTabButtonDown2(app, event)
                              
                        path = mfilename('fullpath');
            [path,file_name, ~]=fileparts(path);
            rehosp_data= readtable([path, '\rehosp_data.xlsx']);
            my_rehosp_inds=unique(rehosp_data.Indikator);
            app.IndikatorDropDown.Items = my_rehosp_inds;
                app.IndikatorDropDown.ItemsData=[1:numel(my_rehosp_inds)];
                app.tab_ind=3;   
                disp(num2str(app.tab_ind))
                 app.IQMMortalittsindikatorenLabel.Text='Ungeplante Rehospitalisationen';
           drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.IndikatorDropDownLabel = uilabel(app.UIFigure);
            app.IndikatorDropDownLabel.Text = 'Indikator';
            app.IndikatorDropDown = uidropdown(app.UIFigure);
            app.IndikatorDropDown.DropDownOpeningFcn = createCallbackFcn(app, @IndikatorDropDownOpening, true);
            app.IndikatorDropDown.ValueChangedFcn = createCallbackFcn(app, @IndikatorDropDownValueChanged, true);
            app.IndikatorDropDown.ClickedFcn = createCallbackFcn(app, @IndikatorDropDownClicked, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'qmps.png';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MortalittTab = uitab(app.TabGroup);
            app.MortalittTab.Title = 'Mortalität';
            app.MortalittTab.ButtonDownFcn = createCallbackFcn(app, @MortalittTabButtonDown, true);
            app.PSITab = uitab(app.TabGroup);
            app.PSITab.Title = 'PSI';
            app.PSITab.ButtonDownFcn = createCallbackFcn(app, @PSITabButtonDown, true);
            app.RehospTab = uitab(app.TabGroup);
            app.RehospTab.Title = 'Rehosp';
            app.RehospTab.ButtonDownFcn = createCallbackFcn(app, @RehospTabButtonDown2, true);
            app.IQMMortalittsindikatorenLabel = uilabel(app.UIFigure);
            app.IQMMortalittsindikatorenLabel.Text = 'IQM-Mortalitätsindikatoren';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iqm_dashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end